classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RunButton                   matlab.ui.control.Button
        XaxisEditField_2Label       matlab.ui.control.Label
        XaxisEditField              matlab.ui.control.EditField
        YaxisEditFieldLabel         matlab.ui.control.Label
        YaxisEditField              matlab.ui.control.EditField
        SpeedEditFieldLabel         matlab.ui.control.Label
        SpeedEditField              matlab.ui.control.EditField
        AccelerationEditFieldLabel  matlab.ui.control.Label
        AccelerationEditField       matlab.ui.control.EditField
        SpinangleEditFieldLabel     matlab.ui.control.Label
        SpinangleEditField          matlab.ui.control.EditField
        WristEditFieldLabel         matlab.ui.control.Label
        WristEditField              matlab.ui.control.EditField
        ShoulderEditFieldLabel      matlab.ui.control.Label
        ShoulderEditField           matlab.ui.control.EditField
        ElbowEditFieldLabel         matlab.ui.control.Label
        ElbowEditField              matlab.ui.control.EditField
        IPAddressEditFieldLabel     matlab.ui.control.Label
        IPAddressEditField          matlab.ui.control.EditField
        ConnectButton               matlab.ui.control.Button
        PortEditFieldLabel          matlab.ui.control.Label
        PortEditField               matlab.ui.control.NumericEditField
        ShortcutsLabel              matlab.ui.control.Label
        X05mButton                  matlab.ui.control.Button
        Y05mButton                  matlab.ui.control.Button
        Spin360Button               matlab.ui.control.Button
        Spin360Button_2             matlab.ui.control.Button
        DropPenButton               matlab.ui.control.Button
        RaisePenButton              matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        X05mButton_2                matlab.ui.control.Button
        Y05mButton_2                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunPushed(app, event)
            x_val = app.XaxisEditField.Value;
            y_val = app.YaxisEditField.Value;
            Spd = app.SpeedEditField.Value;
            Accel = app.AccelerationEditField.Value;
            Spin = app.SpinangleEditField.Value;
            w_agl = app.WristEditField.Value;
            s_agl = app.ShoulderEditField.Value;
            e_agl = app.ElbowEditField.Value;
            
            cmd_all = strcat(x_val,",",y_val,",",Spd,",",Accel,",",Spin,",",w_agl,",",s_agl,",",e_agl);
            global u;
            
            fwrite(u,cmd_all);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            global u;
            u = udp(app.IPAddressEditField.Value,app.PortEditField.Value);
            fopen(u);
        end

        % Button pushed function: X05mButton
        function X05mButtonPushed(app, event)
            global u;
            fwrite(u,"50,0,12,5,0,35,170,180");
        end

        % Button pushed function: Y05mButton
        function Y05mButtonPushed(app, event)
            global u;
            fwrite(u,"0,50,12,5,0,35,170,180");
        end

        % Button pushed function: Spin360Button
        function Spin360ButtonPushed(app, event)
            global u;
            fwrite(u,"0,0,12,5,400,35,170,180");
        end

        % Button pushed function: Spin360Button_2
        function Spin360Button_2Pushed(app, event)
            global u;
            fwrite(u,"0,0,12,5,400,35,170,180");
        end

        % Button pushed function: DropPenButton
        function DropPenButtonPushed(app, event)
            global u;
            fwrite(u,"0,0,12,5,0,35,170,50");
        end

        % Button pushed function: RaisePenButton
        function RaisePenButtonPushed(app, event)
            global u;
            fwrite(u,"0,0,12,5,0,35,170,180");
        end

        % Button pushed function: X05mButton_2
        function X05mButton_2Pushed(app, event)
            global u;
            fwrite(u,"-50,0,12,5,0,35,170,180");
        end

        % Button pushed function: Y05mButton_2
        function Y05mButton_2Pushed(app, event)
            global u;
            fwrite(u,"0,-50,12,5,0,35,170,180");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunPushed, true);
            app.RunButton.Text = 'Run';
            app.XaxisEditField_2Label = uilabel(app.UIFigure);
            app.XaxisEditField_2Label.Text = 'X axis';
            app.XaxisEditField = uieditfield(app.UIFigure, 'text');
            app.XaxisEditField.Value = '0';
            app.YaxisEditFieldLabel = uilabel(app.UIFigure);
            app.YaxisEditFieldLabel.Text = 'Y axis';
            app.YaxisEditField = uieditfield(app.UIFigure, 'text');
            app.YaxisEditField.Value = '0';
            app.SpeedEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.UIFigure, 'text');
            app.SpeedEditField.Value = '12';
            app.AccelerationEditFieldLabel = uilabel(app.UIFigure);
            app.AccelerationEditFieldLabel.Text = 'Acceleration';
            app.AccelerationEditField = uieditfield(app.UIFigure, 'text');
            app.AccelerationEditField.Value = '5';
            app.SpinangleEditFieldLabel = uilabel(app.UIFigure);
            app.SpinangleEditFieldLabel.Text = 'Spin angle';
            app.SpinangleEditField = uieditfield(app.UIFigure, 'text');
            app.SpinangleEditField.Value = '0';
            app.WristEditFieldLabel = uilabel(app.UIFigure);
            app.WristEditFieldLabel.Text = 'Wrist';
            app.WristEditField = uieditfield(app.UIFigure, 'text');
            app.WristEditField.Value = '35';
            app.ShoulderEditFieldLabel = uilabel(app.UIFigure);
            app.ShoulderEditFieldLabel.Text = 'Shoulder';
            app.ShoulderEditField = uieditfield(app.UIFigure, 'text');
            app.ShoulderEditField.Value = '170';
            app.ElbowEditFieldLabel = uilabel(app.UIFigure);
            app.ElbowEditFieldLabel.Text = 'Elbow';
            app.ElbowEditField = uieditfield(app.UIFigure, 'text');
            app.ElbowEditField.Value = '180';
            app.IPAddressEditFieldLabel = uilabel(app.UIFigure);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.UIFigure, 'text');
            app.IPAddressEditField.Value = '129.127.225.186';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'numeric');
            app.PortEditField.Value = 2390;
            app.ShortcutsLabel = uilabel(app.UIFigure);
            app.ShortcutsLabel.Text = 'Shortcuts:';
            app.X05mButton = uibutton(app.UIFigure, 'push');
            app.X05mButton.ButtonPushedFcn = createCallbackFcn(app, @X05mButtonPushed, true);
            app.X05mButton.Text = 'X 0.5m';
            app.Y05mButton = uibutton(app.UIFigure, 'push');
            app.Y05mButton.ButtonPushedFcn = createCallbackFcn(app, @Y05mButtonPushed, true);
            app.Y05mButton.Text = 'Y 0.5m';
            app.Spin360Button = uibutton(app.UIFigure, 'push');
            app.Spin360Button.ButtonPushedFcn = createCallbackFcn(app, @Spin360ButtonPushed, true);
            app.Spin360Button.Text = 'Spin 360';
            app.Spin360Button_2 = uibutton(app.UIFigure, 'push');
            app.Spin360Button_2.ButtonPushedFcn = createCallbackFcn(app, @Spin360Button_2Pushed, true);
            app.Spin360Button_2.Text = 'Spin -360';
            app.DropPenButton = uibutton(app.UIFigure, 'push');
            app.DropPenButton.ButtonPushedFcn = createCallbackFcn(app, @DropPenButtonPushed, true);
            app.DropPenButton.Text = 'Drop Pen';
            app.RaisePenButton = uibutton(app.UIFigure, 'push');
            app.RaisePenButton.ButtonPushedFcn = createCallbackFcn(app, @RaisePenButtonPushed, true);
            app.RaisePenButton.Text = 'Raise Pen';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Snipaste_2020-02-27_10-20-15.jpg';
            app.X05mButton_2 = uibutton(app.UIFigure, 'push');
            app.X05mButton_2.ButtonPushedFcn = createCallbackFcn(app, @X05mButton_2Pushed, true);
            app.X05mButton_2.Text = 'X -0.5m';
            app.Y05mButton_2 = uibutton(app.UIFigure, 'push');
            app.Y05mButton_2.ButtonPushedFcn = createCallbackFcn(app, @Y05mButton_2Pushed, true);
            app.Y05mButton_2.Text = 'Y -0.5m';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end